classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        RecordButton         matlab.ui.control.Button
        SignalData           matlab.ui.control.UIAxes
        Spectrogram          matlab.ui.control.UIAxes
        AnimalDropDownLabel  matlab.ui.control.Label
        AnimalDropDown       matlab.ui.control.DropDown
        YourTurnLabel        matlab.ui.control.Label
        AnimalKingdomLabel   matlab.ui.control.Label
        AnimalSignalData     matlab.ui.control.UIAxes
        AnimalSpectrogram    matlab.ui.control.UIAxes
        PlaySoundButton      matlab.ui.control.Button
    end


    methods (Access = private)
    
        function plotSignal(app, y, t, isAnimal)
            if isAnimal == 0
            % Plot it raw
                plot(app.SignalData, linspace(0,t,length(y)), y);
            else
                plot(app.AnimalSignalData, linspace(0,t,length(y)), y);
            end                
        end
        
        function plotSpectrogram(app, s, fs, T, w, nfft, Slim, alpha, cmap, cbar, type, isAnimal)
            %__________________________________________________________________________________________________________________
            % VALIDATE INPUTS, SET DEFAULTS
            switch nargin
                case 3, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; w=@hamming; T=[18,1]; nfft=1024; fs=8000;
                case 4, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; w=@hamming; T=[18,1]; nfft=1024; 
                case 5, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; w=@hamming; T=[18,1]; 
                case 6, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; w=@hamming; 
                case 7, type='per'; cbar=false; cmap='default'; alpha=false; Slim=[-59,-1]; 
                case 8, type='per'; cbar=false; cmap='default'; alpha=false;
                case 9, type='per'; cbar=false; cmap='default'; 
                case 10, type='per'; cbar=false; 
                case 11, type='per'; 
                case 12
                otherwise, error('Invalid number of input arguments.');
            end
            if(isstr(s)), [s, fs] = wavread(s); end; % read audio data from file

            Tw = T(1);                          % frame width (ms)
            Ts = T(2);                          % frame shift (ms)
            Nw = round(fs*Tw*0.001);            % frame width (samples)
            Ns = round(fs*Ts*0.001);            % frame shift (samples)
            N  = length(s);                     % length of speech signal (samples)
            Smin = Slim(1);                     % lower normalized dynamic range limit 
            Smax = Slim(2);                     % upper normalized dynamic range limit
            if(isstr(w)), w = str2func(w); end; % obtain window function handle from string input
            %__________________________________________________________________________________________________________________
            % SPEECH PREPROCESSING
            if(islogical(alpha) && alpha), s = filter([1 -0.95],1,s);   % apply a typical preemphasis filter
            elseif(~islogical(alpha)) s = filter(alpha,1,s); end;       % apply custom preemphasis filter
            %__________________________________________________________________________________________________________________
            %__________________________________________________________________________________________________________________
            % GET SPECTROGRAM DATA 
            
            disp(size(s))
            disp(size(w(Nw).'))
            disp(size(Nw-Ns))
            [S,F,T] = spectrogram(s,w(Nw).',Nw-Ns,nfft,fs);            % MATLAB's new spectrogram function
            %__________________________________________________________________________________________________________________
            % SET DYNAMIC RANGE 
            S = abs(S);                         % compute magnitude spectrum 
            S = S/max(max(S));                  % normalize magntide spectrum
        %    S(S<eps) = eps;                     % prevent zero spectral magnitude values 
            S = 20*log10(S);                    % compute power spectrum in dB
            spec = S;
            %__________________________________________________________________________________________________________________
            % PLOT AND LABEL RESULTS 
            if isAnimal == 0
                imagesc(app.Spectrogram, T, F, S, [Smin Smax]);
            else
                imagesc(app.AnimalSpectrogram, T, F, S, [Smin Smax]);
            end
            %handle = imagesc(T, F, S, 'CDataMapping', 'direct');
%             axis('xy');
%             axis([0 N/fs  0 fs/2]);
        %    xlabel('time (s)', 'FontSize', 8, 'FontWeight', 'n');
        %    ylabel('frequency (Hz)', 'FontSize', 8, 'FontWeight', 'n');
        %    set(gca,'YDir','normal', 'FontSize', 6);
            if(cbar), colorbar('FontSize',6); end
        end
    end


    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            app.RecordButton.BackgroundColor = 'green';
            app.RecordButton.Text = 'Start Recording';
            recObj = audiorecorder;

%             disp('Start speaking');
            t = 3;
            recordblocking(recObj, t);
%             disp('Done recording');
            app.RecordButton.BackgroundColor = 'red';
            app.RecordButton.Text = 'Stop Recording';
            y = getaudiodata(recObj);
            plotSignal(app, y, t, 0);
            plotSpectrogram(app, y, 8000, [18 1], @hamming, 256, [-45 -2], false, 'default', false, 'lp', 0);
            pause(1)
            
            app.RecordButton.BackgroundColor = [0.96, 0.96, 0.96];
            app.RecordButton.Text = 'Record';            
        end

        % Value changed function: AnimalDropDown
        function AnimalDropDownValueChanged(app, event)
            value = app.AnimalDropDown.Value;
         
            if strcmp(value,'Gibbon Monkey')
                [y, Fs] = audioread('gibbon.wav');                
            elseif strcmp(value,'Orca Whale')
                [y, Fs] = audioread('orca.wav') ;
            elseif strcmp(value,'Elephant')
                [y, Fs] = audioread('elephant.wav');
            elseif strcmp(value,'Dog')
                [y, Fs] = audioread('dog.wav');
            end
            dt = 1/Fs;
            t = (length(y)*dt)-dt;
            plotSignal(app, y, t, 1);
            plotSpectrogram(app, y(:,1), 8000, [18 1], @hamming, 128, [-45 -2], false, 'default', false, 'lp', 1);
        end

        % Button pushed function: PlaySoundButton
        function PlaySoundButtonPushed(app, event)
            value = app.AnimalDropDown.Value;
            if strcmp(value,'Gibbon Monkey')
                [y, Fs] = audioread('gibbon.wav');                
            elseif strcmp(value,'Orca Whale')
                [y, Fs]= audioread('orca.wav') ;
            elseif strcmp(value,'Elephant')
                [y, Fs] = audioread('elephant.wav');
            elseif strcmp(value,'Dog')
                [y, Fs] = audioread('dog.wav');
            end
            sound(y,Fs)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.SignalData = uiaxes(app.UIFigure);
            title(app.SignalData, 'Recorded Sound')
            xlabel(app.SignalData, 'Time')
            ylabel(app.SignalData, 'Data')
            app.Spectrogram = uiaxes(app.UIFigure);
            title(app.Spectrogram, 'Spectrogram')
            xlabel(app.Spectrogram, 'Samples')
            ylabel(app.Spectrogram, 'Frequency')
            app.AnimalDropDownLabel = uilabel(app.UIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalDropDown = uidropdown(app.UIFigure);
            app.AnimalDropDown.Items = {'Gibbon Monkey', 'Orca Whale', 'Elephant', 'Dog'};
            app.AnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalDropDownValueChanged, true);
            app.AnimalDropDown.Value = 'Gibbon Monkey';
            app.YourTurnLabel = uilabel(app.UIFigure);
            app.YourTurnLabel.Text = 'Your Turn';
            app.AnimalKingdomLabel = uilabel(app.UIFigure);
            app.AnimalKingdomLabel.Text = 'Animal Kingdom';
            app.AnimalSignalData = uiaxes(app.UIFigure);
            title(app.AnimalSignalData, 'Recorded Sound')
            xlabel(app.AnimalSignalData, 'Time')
            ylabel(app.AnimalSignalData, 'Data')
            app.AnimalSpectrogram = uiaxes(app.UIFigure);
            title(app.AnimalSpectrogram, 'Spectrogram')
            xlabel(app.AnimalSpectrogram, 'X')
            ylabel(app.AnimalSpectrogram, 'Frequency')
            app.PlaySoundButton = uibutton(app.UIFigure, 'push');
            app.PlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySoundButtonPushed, true);
            app.PlaySoundButton.Text = 'Play Sound';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end